classdef Practica_3_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RaicesTextArea       matlab.ui.control.TextArea
        RaicesTextAreaLabel  matlab.ui.control.Label
        YmaxEditField        matlab.ui.control.NumericEditField
        YmaxEditFieldLabel   matlab.ui.control.Label
        YminEditField        matlab.ui.control.NumericEditField
        YminEditFieldLabel   matlab.ui.control.Label
        XmaxEditField        matlab.ui.control.NumericEditField
        XmaxEditFieldLabel   matlab.ui.control.Label
        XminEditField        matlab.ui.control.NumericEditField
        XminEditFieldLabel   matlab.ui.control.Label
        GraficarButton       matlab.ui.control.Button
        ftEditField          matlab.ui.control.EditField
        ftEditFieldLabel     matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            xchar= app.ftEditField.Value;
            xsym= str2sym(xchar);
            xvector= sym2poly(xsym);
            xroots=roots(xvector);
            app.RaicesTextArea.Value= string(xroots);
            tmin=app.XminEditField.Value;
            tmax=app.XmaxEditField.Value;
            lmin=app.YminEditField.Value;
            lmax=app.YmaxEditField.Value;
            x=linspace(tmin,tmax,100);
            y=polyval(xvector,x);
            plot(app.UIAxes,x,y,'b-');

            if isinf(lmin)
                lmin=min(y);
            end
            if isinf(lmax)
                lmax=max(y);
            end
            app.UIAxes.XLim=[tmin,tmax];
            app.UIAxes.YLim=[lmin,lmax];

            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Practica 3')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.ftEditFieldLabel = uilabel(app.UIFigure);
            app.ftEditFieldLabel.Text = 'f(t)=';
            app.ftEditField = uieditfield(app.UIFigure, 'text');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Value = -1;
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Value = 1;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Value = -Inf;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Value = Inf;
            app.RaicesTextAreaLabel = uilabel(app.UIFigure);
            app.RaicesTextAreaLabel.Text = 'Raices';
            app.RaicesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica_3_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end